classdef winRFDataHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid                           matlab.ui.container.GridLayout
        Document                       matlab.ui.container.GridLayout
        chReportGrid                   matlab.ui.container.GridLayout
        chReportLabel                  matlab.ui.control.Label
        chReportHTML                   matlab.ui.control.HTML
        UITable                        matlab.ui.control.Table
        plot_Panel2                    matlab.ui.container.Panel
        plot_Panel1                    matlab.ui.container.Panel
        LeftPanelVisibility            matlab.ui.control.Image
        LeftPanel                      matlab.ui.container.Panel
        LeftPanelGrid                  matlab.ui.container.GridLayout
        Tab3_infoGrid                  matlab.ui.container.GridLayout
        misc_Clipboard                 matlab.ui.control.Button
        misc_SelectedStationPanel      matlab.ui.container.Panel
        misc_SelectedStationGrid       matlab.ui.container.GridLayout
        misc_SelectedStation           matlab.ui.control.HTML
        misc_SelectedStationLabel      matlab.ui.control.Label
        misc_OperationPanel            matlab.ui.container.Panel
        misc_OperationGrid             matlab.ui.container.GridLayout
        misc_PDFLabel                  matlab.ui.control.Label
        misc_PDFButton                 matlab.ui.control.Button
        misc_ExportLabel               matlab.ui.control.Label
        misc_ExportButton              matlab.ui.control.Button
        misc_OperationLabel            matlab.ui.control.Label
        Tab3_titleGrid                 matlab.ui.container.GridLayout
        Tab3_titleImage                matlab.ui.control.Image
        Tab3_titleLabel                matlab.ui.control.Label
        Tab2_infoGrid                  matlab.ui.container.GridLayout
        filter_nRows                   matlab.ui.control.Label
        filter_Tree                    matlab.ui.container.CheckBoxTree
        filter_addImage                matlab.ui.control.Image
        filter_operationPanel          matlab.ui.container.ButtonGroup
        filter_operationValuesPanel    matlab.ui.container.Panel
        filter_operationValuesGrid     matlab.ui.container.GridLayout
        filter_operationLogical        matlab.ui.control.DropDown
        filter_operationLogicalLabel   matlab.ui.control.Label
        filter_operationLogicalReference  matlab.ui.control.DropDown
        filter_operationLogicalReferenceLabel  matlab.ui.control.Label
        filter_operationTextListValue  matlab.ui.control.DropDown
        filter_operationTextFreeValue  matlab.ui.control.EditField
        filter_operationNumValue2      matlab.ui.control.NumericEditField
        filter_operationSeparator      matlab.ui.control.Label
        filter_operationNumValue1      matlab.ui.control.NumericEditField
        filter_operationButton9        matlab.ui.control.ToggleButton
        filter_operationButton8        matlab.ui.control.ToggleButton
        filter_operationButton7        matlab.ui.control.ToggleButton
        filter_operationButton6        matlab.ui.control.ToggleButton
        filter_operationButton5        matlab.ui.control.ToggleButton
        filter_operationButton4        matlab.ui.control.ToggleButton
        filter_operationButton3        matlab.ui.control.ToggleButton
        filter_operationButton2        matlab.ui.control.ToggleButton
        filter_operationButton1        matlab.ui.control.ToggleButton
        filter_TypePanel               matlab.ui.container.ButtonGroup
        filter_TypeRadioButton12       matlab.ui.control.RadioButton
        filter_TypeRadioButton11       matlab.ui.control.RadioButton
        filter_TypeRadioButton10       matlab.ui.control.RadioButton
        filter_TypeRadioButton9        matlab.ui.control.RadioButton
        filter_TypeRadioButton8        matlab.ui.control.RadioButton
        filter_TypeRadioButton7        matlab.ui.control.RadioButton
        filter_TypeRadioButton6        matlab.ui.control.RadioButton
        filter_TypeRadioButton5        matlab.ui.control.RadioButton
        filter_TypeRadioButton4        matlab.ui.control.RadioButton
        filter_TypeRadioButton3        matlab.ui.control.RadioButton
        filter_TypeRadioButton2        matlab.ui.control.RadioButton
        filter_TypeRadioButton1        matlab.ui.control.RadioButton
        Tab2_titleGrid                 matlab.ui.container.GridLayout
        Tab2_titleImage                matlab.ui.control.Image
        Tab2_titleLabel                matlab.ui.control.Label
        Tab1_infoGrid                  matlab.ui.container.GridLayout
        points_Panel                   matlab.ui.container.Panel
        points_Grid                    matlab.ui.container.GridLayout
        points_editButton              matlab.ui.control.Button
        points_Longitude               matlab.ui.control.NumericEditField
        points_LongitudeLabel          matlab.ui.control.Label
        points_Latitude                matlab.ui.control.NumericEditField
        points_LatitudeLabel           matlab.ui.control.Label
        points_Tree                    matlab.ui.container.Tree
        points_refreshGrid             matlab.ui.container.GridLayout
        points_refreshImage            matlab.ui.control.Image
        points_Label                   matlab.ui.control.Label
        layout_Grid                    matlab.ui.container.GridLayout
        layout_pdfButton               ccTools.Button
        layout_tableButton             ccTools.Button
        layout_histogramButton         ccTools.Button
        layout_mapButton               ccTools.Button
        layout_Label                   matlab.ui.control.Label
        Description                    matlab.ui.control.Label
        Tab1_titleGrid                 matlab.ui.container.GridLayout
        Tab1_titleImage                matlab.ui.control.Image
        Tab1_titleLabel                matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        ContextMenu_delButton          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        RootFolder
        userPath

        UIAxes1
        UIAxes2
        UIAxes3

        compVisibilityStatus = [false, false, false, false]

        rfDataHub
        rfDataHubSummary
        rfDataHubLogical

        pointsTable = table('Size',          [0, 4],                               ...
                            'VariableTypes', {'cell', 'double', 'double', 'cell'}, ...
                            'VariableNames', {'Type', 'Latitude', 'Longitude', 'Location'})

        filterTable = table('Size',          [0, 9],                                                                      ...
                            'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                            'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Eixo geográfico (MAPA)
            t1 = tiledlayout(app.plot_Panel1, 1, 1, "Padding", "tight");

            app.UIAxes1 = geoaxes(t1, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater', 'Box', 'off');
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.LatitudeLabel.String  = '';
            app.UIAxes1.LongitudeLabel.String = '';
            hold(app.UIAxes1, 'on')

            geolimits(app.UIAxes1, [-35 10], [-95, -15])
            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            % Eixo regular
            t2 = tiledlayout(app.plot_Panel2, 1, 2, "Padding", "tight", "TileSpacing", "tight");

            app.UIAxes2 = uiaxes(t2, Color=[.98 .98 .98], ...
                                     FontSize=8,          ...
                                     YScale='log',        ...
                                     YMinorTick='on',     ...
                                     Interactions=[]);
            app.UIAxes2.Layout.Tile = 1;
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')

            app.UIAxes3 = uiaxes(t2, Color=[.98 .98 .98], ...
                                     FontSize=8,          ...
                                     YScale='log',        ...
                                     YMinorTick='on',     ...
                                     Interactions=[]);
            app.UIAxes3.Layout.Tile = 2;
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, '')

            % Interações
            addToolbarMapButton(axtoolbar(app.UIAxes1, "default"), "basemap", BasemapNames=["darkwater", "satellite", "streets-light", "streets-dark", "topographic", "streets", "colorterrain", "grayterrain"])
            app.UIAxes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
            enableDefaultInteractivity(app.UIAxes1)

            Interactions = {'datacursor', 'pan', 'zoomin', 'restoreview'};
            plotFcn.axesInteractions(app.UIAxes2, Interactions)
            plotFcn.axesInteractions(app.UIAxes3, Interactions)
            drawnow
        end


        %-----------------------------------------------------------------%
        function startup_RFDataHub(app)
            global RFDataHub
            global RFDataHub_info

            app.rfDataHub             = RFDataHub;
            
            % Contorna erro da função inROI, que retorna como se todos os
            % pontos estivessem internos ao ROI, quando as coordenadas
            % estão em float32. No float64 isso não acontece... abrir BUG
            % na Mathworks.
            app.rfDataHub.Latitude    = double(app.rfDataHub.Latitude);
            app.rfDataHub.Longitude   = double(app.rfDataHub.Longitude);

            app.rfDataHub.ID          = "#" + string((1:height(RFDataHub))');
            app.rfDataHub.Description = "[" + string(RFDataHub.Source) + "] " + string(RFDataHub.Status) + ", " + string(RFDataHub.StationClass) + ", " + string(RFDataHub.Name) + " (Fistel=" + string(RFDataHub.Fistel) + ", M=" + string(RFDataHub.MergeCount) + "), " + string(RFDataHub.Location) + "/" + string(RFDataHub.State);            
            app.rfDataHub.Distance    = -1*ones(height(RFDataHub), 1);

            app.rfDataHubSummary      = summary(RFDataHub);
            app.Description.Text      = sprintf(app.Description.Text, RFDataHub_info.ReleaseDate);

            if ~isempty(app.points_Tree.SelectedNodes)
                points_TreeSelectionChanged(app)
            else
                filter_TableFiltering(app)
            end
        end


        %-----------------------------------------------------------------%
        function startup_NodeList(app)
            if ~isempty(app.CallingApp)
                idx = strcmp(app.pointsTable.Type, 'Station');
                app.pointsTable(idx,:) = [];

                for ii = 1:numel(app.CallingApp.specData)
                    if app.CallingApp.specData(ii).GPS.Status
                        newPoint     = {'Station',                                 ...
                                        app.CallingApp.specData(ii).GPS.Latitude,  ...
                                        app.CallingApp.specData(ii).GPS.Longitude, ...
                                        app.CallingApp.specData(ii).GPS.Location};
                        newPointFlag = true;

                        for jj = 1:height(app.pointsTable)
                            if isequal(newPoint(2:4), app.pointsTable{jj,2:4})
                                newPointFlag = false;
                                break
                            end
                        end

                        if newPointFlag
                            app.pointsTable(end+1,:) = newPoint;
                        end
                    end
                end
            end

            points_TreeBuilding(app, 1)
        end


        %-----------------------------------------------------------------%
        function points_TreeBuilding(app, selectedID)
            if ~isempty(app.points_Tree.Children)
                delete(app.points_Tree.Children)
            end

            if ~isempty(app.pointsTable)
                for ii = 1:height(app.pointsTable)
                    switch app.pointsTable.Type{ii}
                        case 'Station'; treeIcon = 'LT_antenna.png';
                        case 'Manual';  treeIcon = 'LT_manual.png';
                    end

                    uitreenode(app.points_Tree, 'Text', sprintf('%s (%.6fº, %.6fº)', app.pointsTable.Location{ii}, ...
                                                                                     app.pointsTable.Latitude(ii), ...
                                                                                     app.pointsTable.Longitude(ii)));    
                end

                if ~ismember(selectedID, 1:height(app.pointsTable))
                    selectedID = 1;
                end
                app.points_Tree.SelectedNodes = app.points_Tree.Children(selectedID);
            end
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    parentNode = uitreenode(app.filter_Tree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                            app.filterTable.Type{ii},                      ...
                                                                                                            app.filterTable.Operation{ii},                 ...
                                                                                                            filter_Value(app, app.filterTable.Value{ii})), ...
                                                             'NodeData', ii, 'ContextMenu', app.ContextMenu);
                    if app.filterTable.Enable(ii)
                        checkedNodes = [checkedNodes, parentNode];
                    end
    
                    idx2 = find(app.filterTable.RelatedID == ii)';                
                    for jj = idx2
                        childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                          app.filterTable.Type{jj},                      ...
                                                                                                          app.filterTable.Operation{jj},                 ...
                                                                                                          filter_Value(app, app.filterTable.Value{jj})), ...
                                                           'NodeData', jj, 'ContextMenu', app.ContextMenu);
    
                        if app.filterTable.Enable(jj)
                            checkedNodes = [checkedNodes, childNode];
                        end
                    end
                end
                app.filter_Tree.CheckedNodes = checkedNodes;
                expand(app.filter_Tree, 'all')
            end

            app.filter_operationLogicalReference.Items = [{''}, cellstr("#" + string((idx1)))];
        end


        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if numel(filterValue) == 1
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end


        %-----------------------------------------------------------------%
        function filter_operationPanelLayout(app, filterType, filterDefault)
            
            filter_DefaultValues(app)
            filter_operationLogicalReferenceValueChanged(app)

            hComp = findobj(app.filter_operationPanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_operationPanel.SelectedObject;
            if ~selectedButton.Enable
                if strcmp(filterType, 'ROI'); app.filter_operationButton3.Value = 1; % CONTÉM
                else;                         app.filter_operationButton1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {22,0,0};

                case 'textFree'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,22,0};

                case {'textList', 'ROI'}
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,0,22};
                    app.filter_operationTextListValue.Items = filterDefault;
            end
        end


        %-----------------------------------------------------------------%
        function filter_DefaultValues(app)
            app.filter_operationNumValue1.Value        = -1;
            app.filter_operationNumValue2.Value        = -1;
            app.filter_operationTextFreeValue.Value    = '';
            app.filter_operationTextListValue.Items    = {};
            app.filter_operationLogical.Items          = {'E (&&)'};
        end


        %-----------------------------------------------------------------%
        function filter_ROICallbacks(app, src, event)
            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes1)
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes1)
                    filter_TableFiltering(app)
                    if isvalid(event.Source)
                        uistack(event.Source, 'top')
                    end
            end            
        end


        %-----------------------------------------------------------------%
        function filter_TableFiltering(app)
            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

            app.rfDataHubLogical  = fcn.TableFiltering(app.rfDataHub, app.filterTable);
            app.UITable.Data      = app.rfDataHub(app.rfDataHubLogical,[30, 1, 6:7, 31, 4:5, 12, 13, 29, 32]);
            app.filter_nRows.Text = sprintf('%d DE %d REGISTROS', sum(app.rfDataHubLogical), numel(app.rfDataHubLogical));

            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            drawnow

            delete(d)
        end


        %-----------------------------------------------------------------%
        function plot_General(app)
            geolimits(app.UIAxes1, 'manual')

            hObjects = app.UIAxes1.Children;
            idx = contains(arrayfun(@(x) x.Tag, app.UIAxes1.Children, 'UniformOutput', false), 'ROI');
            delete(hObjects(~idx))

            rfDataHubFlag = height(app.UITable.Data);
            if rfDataHubFlag
                p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude, MarkerEdgeColor='#009578', Tag='Station');
                geolimits(app.UIAxes1, 'auto')
            else
                geolimits(app.UIAxes1, [-35 10], [-95, -15])
            end

            uistack(hObjects(idx))

            p2 = histogram(app.UIAxes2, app.UITable.Data.Frequency,            EdgeColor='none', FaceColor="#009578");
            p3 = histogram(app.UIAxes3, categorical(app.UITable.Data.Service), EdgeColor='none', FaceColor="#009578");
            
            if rfDataHubFlag
                plot_DataTipTemplate(app, 'Geographic', p1, app.UITable.Data)
                plot_DataTipTemplate(app, 'Histogram1', p2, [])
                plot_DataTipTemplate(app, 'Histogram2', p3, [])
            end
        end


        %-----------------------------------------------------------------%
        function plot_SelectedRow(app)
            hSelected = findobj(app.UIAxes1, Tag='SelectedRow');
            
            idx = app.UITable.Selection(1);
            if idx
                hold(app.UIAxes1, 'on')

                if isempty(hSelected)
                    hSelected = geoscatter(app.UIAxes1, app.UITable.Data.Latitude(idx), app.UITable.Data.Longitude(idx), MarkerEdgeColor='#A2142F', ...
                                                                                                                         MarkerFaceColor='#A2142F', ...
                                                                                                                         Tag='SelectedRow');
                else
                    set(hSelected, 'LatitudeData',  app.UITable.Data.Latitude(idx), ...
                                   'LongitudeData', app.UITable.Data.Longitude(idx))
                end
                plot_DataTipTemplate(app, 'Geographic', hSelected, app.UITable.Data(idx,:))

                if strcmp(char(app.UITable.Data{idx,10}), '-1')
                    app.misc_PDFButton.Enable = 0;
                else
                    app.misc_PDFButton.Enable = 1;
                end

                hold(app.UIAxes1, 'off')
            else
                delete(hSelected)
            end
        end


        %-----------------------------------------------------------------%
        function plot_Nodes(app)
            if ~isempty(app.nodeTable)
                hold(app.UIAxes1, 'on')

                if height(app.pointsTable) > 1
                    p1 = geoscatter(app.UIAxes1, app.pointsTable.Latitude, app.pointsTable.Longitude, 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'Tag', 'OthersNodes');
                    plot_DataTipTemplate(app, 'SelectedNode', p1, [])
                end

                idx = app.points_Tree.SelectedNodes.DataNode;
                p2 = geoscatter(app.UIAxes1, app.nodeTable.lat(idx), app.nodeTable.long(idx), 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'MarkerFaceColor', '#A2142F', 'Tag', 'SelectedNode');
                plot_DataTipTemplate(app, 'SelectedNode', p2, [])

                hold(app.UIAxes1, 'off')
            end
        end


        %-----------------------------------------------------------------%
        function plot_DataTipTemplate(app, pType, pHandle, hTable)
            set(pHandle.DataTipTemplate, FontName='Calibri', FontSize=10)

            switch pType
                case 'Geographic'
                    pHandle.DataTipTemplate.DataTipRows(1) = dataTipTextRow('', hTable.Frequency, '%.3f MHz');
                    pHandle.DataTipTemplate.DataTipRows(2) = dataTipTextRow('', hTable.Distance,  '%.0f km');

                    ROWS = height(hTable);
                    if ROWS == 1
                        pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', {hTable{:,1}});
                        pHandle.DataTipTemplate.DataTipRows(4) = dataTipTextRow('',    {hTable{:,5}});

                    elseif ROWS > 1
                        pHandle.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', hTable{:,1});
                        pHandle.DataTipTemplate.DataTipRows(4) = dataTipTextRow('',    hTable{:,5});
                    end

                    pHandle.DataTipTemplate.DataTipRows = pHandle.DataTipTemplate.DataTipRows([3,1,4,2]);

                case 'SelectedNode'
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Lon:';

                case 'Histogram1'
                    pHandle.DataTipTemplate.DataTipRows = flip(pHandle.DataTipTemplate.DataTipRows);
                    
                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Banda (MHz):';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Registros:';

                case 'Histogram2'
                    pHandle.DataTipTemplate.DataTipRows = flip(pHandle.DataTipTemplate.DataTipRows);

                    pHandle.DataTipTemplate.DataTipRows(1).Label = 'Serviço:';
                    pHandle.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % O RFDataHub está sendo construído para ser um módulo do appAnalise,
            % mas também deverá ser compilado como um webapp. Por essa razão,
            % precisam ser feitos os seguintes ajustes...
            if nargin == 1
                global RFDataHub
                global RFDataHub_info

                app.RootFolder = fileparts(fileparts(mfilename('fullpath')));
                
                userPaths = [class.Constants.userPaths; tempdir];
                for ii = 1:numel(userPaths)
                    if isfolder(userPaths{ii})
                        app.userPath = userPaths{ii};
                        break
                    end
                end
            
                if isempty(RFDataHub) || isempty(RFDataHub_info)
                    class.RFDataHub.read(app.RootFolder)
                end
                
            else
                app.CallingApp = mainapp;
                app.RootFolder = mainapp.RootFolder;
                app.userPath   = mainapp.menu_userPath.Value;
            end
            
            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';
            app.UITable.Visible      = 0;

            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_titleImage))
            layout_compButtonPushed(app, struct('Source', app.layout_mapButton))
            
            startup_AxesCreation(app)
            startup_NodeList(app)
            startup_RFDataHub(app)
            drawnow

            filter_typePanelSelectionChanged(app)
            
            ccTic = tic;
            ccToc = toc(ccTic);
            while ~ccTools.compCustomization(app.UITable, 'fontSize', '11px')
                pause(.250)
                if ccToc > 3
                    break
                end
            end

            warning('off', 'MATLAB:table:RowsAddedExistingVars')

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin8 = [];
            delete(app)
            
        end

        % Image clicked function: Tab1_titleImage, Tab2_titleImage, 
        % ...and 1 other component
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_titleImage; app.LeftPanelGrid.RowHeight([2,4,6]) = {'1x',0,0};
                case app.Tab2_titleImage; app.LeftPanelGrid.RowHeight([2,4,6]) = {0,'1x',0};
                case app.Tab3_titleImage; app.LeftPanelGrid.RowHeight([2,4,6]) = {0,0,'1x'};
            end
            
        end

        % Image clicked function: LeftPanelVisibility
        function PanelTab_VisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

        end

        % Callback function: layout_histogramButton, layout_mapButton, 
        % ...and 2 other components
        function layout_compButtonPushed(app, event)
            
            switch event.Source
                case app.layout_mapButton;       idx = 1;
                case app.layout_histogramButton; idx = 2;
                case app.layout_tableButton;     idx = 3;
                case app.layout_pdfButton;       idx = 4;
            end
            app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);

            if all(~app.compVisibilityStatus)
                app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);
                return
            end

            % Layout vertical
            if     all(app.compVisibilityStatus([1,2,3]));                               app.Document.RowHeight = {'1x','.5x','.5x'};
            elseif all(app.compVisibilityStatus([1,2]));                                 app.Document.RowHeight = {'1.5x','.5x',0};
            elseif all(app.compVisibilityStatus([1,3]));                                 app.Document.RowHeight = {'1.5x',0,'.5x'};
            elseif all(app.compVisibilityStatus([2,3])); if app.compVisibilityStatus(4); app.Document.RowHeight = {0,'1.5x','.5x'};
                                                         else;                           app.Document.RowHeight = {0,'1x','1x'}; end
            elseif app.compVisibilityStatus(1);                                          app.Document.RowHeight = {'2x',0,0};
            elseif app.compVisibilityStatus(2);                                          app.Document.RowHeight = {0,'2x',0};
            elseif app.compVisibilityStatus(3);          if app.compVisibilityStatus(4); app.Document.RowHeight = {0,'1.5x','.5x'};
                                                         else;                           app.Document.RowHeight = {0,0,'2x'}; end
            else;                                                                        app.Document.RowHeight = {'1x','.5x',0};
            end

            % Layout horizontal
            if any(app.compVisibilityStatus([1,2]));     if app.compVisibilityStatus(4); app.Document.ColumnWidth = {'1x','1x'};
                                                         else;                           app.Document.ColumnWidth = {'1x',0}; end
            else;                                                                        app.Document.ColumnWidth = {0,'1x'};
            end

            % Cor e visibilidade            
            if app.compVisibilityStatus(idx)
                event.Source.BackgroundColor = [0.96,0.96,0.96];
                if idx == 3; app.UITable.Visible = 1;
                end
            else
                event.Source.BackgroundColor = [1,1,1];
                if idx == 3; app.UITable.Visible = 0;
                end
            end
            drawnow
            
        end

        % Image clicked function: points_refreshImage
        function points_refreshImageClicked(app, event)
            
            startup_NodeList(app)

        end

        % Selection changed function: points_Tree
        function points_TreeSelectionChanged(app, event)
            
            idx  = app.points_Tree.SelectedNodes;
            Node = struct('lat', app.pointsTable.Latitude(idx), ...
                          'lon', app.pointsTable.Longitude(idx));

            app.rfDataHub.Distance = round(fcn.gpsDistance(Node, app.rfDataHub(:, 6:7)), 1);

            filter_TableFiltering(app)
            
        end

        % Callback function: points_Latitude, points_Longitude, 
        % ...and 1 other component
        function points_editButtonPushed(app, event)
            
            switch event.Source
                case app.points_editButton
                    app.points_Latitude.Editable  = 1;
                    app.points_Longitude.Editable = 1;
                    focus(app.points_Latitude)
                
                case app.points_Latitude
                    focus(app.points_Longitude)

                case app.points_Longitude
                    selection = uiconfirm(app.UIFigure, 'Confirma?', '', 'Interpreter', 'html');

                    % CASO NÃO, RECOLOCA OS VALORES ANTIGOS... DO PONTO
                    % MARCADO, OU (-1,-1) CASO NÃO EXISTA NADA.

                    app.points_Latitude.Editable  = 0;
                    app.points_Longitude.Editable = 0;
            end

        end

        % Selection changed function: filter_TypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_TypePanel.SelectedObject;
            switch selectedButton
                case app.filter_TypeRadioButton1                            % BASE DE DADOS
                    filterType = 'textList';
                    
                    tempDefault   = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
                    filterDefault = {};
                    for ii = 1:numel(tempDefault)
                        filterDefault = [filterDefault, tempDefault{ii}];
                    end
                    filterDefault = unique(filterDefault);

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA
                    filterType = 'numeric';
                    filterDefault = {};

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    filterType = 'textFree';
                    filterDefault = {};

                case app.filter_TypeRadioButton9                            % UF
                    filterType = 'textList';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_TypeRadioButton12                           % ROI
                    filterType = 'ROI';
                    filterDefault = {'CÍRCULO', 'RETÂNGULO', 'POLÍGONO'};
            end

            filter_operationPanelLayout(app, filterType, filterDefault)
            filter_operationPanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_operationPanel
        function filter_operationPanelSelectionChanged(app, event)
            
            selectedButton = app.filter_operationPanel.SelectedObject;
            switch selectedButton
                case {app.filter_operationButton8, app.filter_operationButton9}
                    app.filter_operationSeparator.Enable = 1;
                    app.filter_operationNumValue2.Enable = 1;
                    
                otherwise
                    app.filter_operationSeparator.Enable = 0;
                    app.filter_operationNumValue2.Enable = 0;
            end
            
        end

        % Value changed function: filter_operationLogicalReference
        function filter_operationLogicalReferenceValueChanged(app, event)
            
            value = app.filter_operationLogicalReference.Value;
            if isempty(value)
                app.filter_operationLogical.Items = {'E (&&)'};
            else
                app.filter_operationLogical.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_TypePanel.SelectedObject;
            selectedFilterOperation = app.filter_operationPanel.SelectedObject;

            if isempty(app.filter_operationLogicalReference.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_operationLogicalReference.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_TypeRadioButton1, ...                      % BASE DE DADOS
                      app.filter_TypeRadioButton9}                          % UF
                    Value = app.filter_operationTextListValue.Value;

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_operationNumValue1.Value, ...
                                 app.filter_operationNumValue2.Value];
                    else
                        Value = app.filter_operationNumValue1.Value;
                    end

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    Value = app.filter_operationTextFreeValue.Value;

                case app.filter_TypeRadioButton12                           % ROI
                    hROI = [];
                    
                    disableDefaultInteractivity(app.UIAxes1)
                    switch app.filter_operationTextListValue.Value
                        case 'CÍRCULO';   roiFcn = 'drawcircle';    roiNameArgument = '';
                        case 'RETÂNGULO'; roiFcn = 'drawrectangle'; roiNameArgument = 'Rotatable=true, ';
                        case 'POLÍGONO';  roiFcn = 'drawpolygon';   roiNameArgument = '';
                    end
                    eval(sprintf('hROI = %s(app.UIAxes1, Color=[0.40,0.73,0.88], LineWidth=1, Deletable=0, FaceSelectable=0, %sTag="ROI_%s");', roiFcn, roiNameArgument, UUID))
                    enableDefaultInteractivity(app.UIAxes1)
    
                    if isempty(hROI.Position)
                        delete(hROI)
                        return
                    end
                    addlistener(hROI, 'MovingROI', @app.filter_ROICallbacks);
                    addlistener(hROI, 'ROIMoved',  @app.filter_ROICallbacks);
                    
                    Value = {hROI};
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                
                return
            end

            app.filterTable(end+1,[1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = Value;

            filter_TreeBuilding(app)
            filter_TableFiltering(app)

        end

        % Menu selected function: ContextMenu_delButton
        function filter_delFilter(app, event)
            
            if ~isempty(app.filter_Tree.SelectedNodes)
                idx1 = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);
    
                if ~isempty(idx1)
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];
    
                    % Apaga os ROI's, caso existentes.
                    idx3 = find(strcmp(app.filterTable.Type, 'ROI'))';
                    for ii = idx3
                        if ismember(ii, idx1)
                            delete(findobj(app.UIAxes1.Children, Tag=sprintf('ROI_%s', app.filterTable.uuid{ii})))
                        end
                    end                
    
                    % E depois atualiza a tabela de filtros, a tabela renderizada
                    % no app e, por fim, o plot.
                    app.filterTable(idx1,:) = [];
    
                    filter_TreeBuilding(app)
                    filter_TableFiltering(app)
                end
            end

        end

        % Callback function: filter_Tree
        function filter_TreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_Tree, '-not', 'Type', 'uicheckboxtree');
            hTreeNodeDataList = arrayfun(@(x) x.NodeData, hTree);
            hCheckedNodeData  = arrayfun(@(x) x.NodeData, app.filter_Tree.CheckedNodes);

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app)
            
        end

        % Button pushed function: misc_ExportButton
        function misc_exportButtonPushed(app, event)
            
                defaultFilename = class.Constants.DefaultFileName(app.userPath, 'RFDataHub', -1);

                [fileName, filePath, fileIndex] = uiputfile({'*.xlsx', 'Excel (*.xlsx)'}, '', defaultFilename);
                figure(app.UIFigure)
                
                if fileIndex
                    d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

                    try
                        tempRFDataHub = class.RFDataHub.ColumnNames(app.rfDataHub(app.rfDataHubLogical,1:29), 'eng2port');
                        writetable(tempRFDataHub, fullfile(filePath, fileName), 'WriteMode', 'overwritesheet')
                    catch ME
                        fprintf('%s\n', jsonencode(ME))
                        layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
                    end

                    delete(d)
                end

        end

        % Button pushed function: misc_PDFButton
        function misc_PDFButtonButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");

            try
                idx = app.UITable.Selection(1);    
                fileName = fullfile(app.userPath, sprintf('Relatório do Canal - Estação %d.pdf', app.UITable.Data.Station(idx)));

                if ~isfile(fileName)
                    websave(fileName, char(app.UITable.Data.URL(idx)));
                end
                app.chReportHTML.HTMLSource = fileName;
                app.chReportLabel.Text      = sprintf('Estação nº %d', app.UITable.Data.Station(idx));

                if ~app.compVisibilityStatus(4)
                    layout_compButtonPushed(app, struct('Source', app.layout_pdfButton))
                end

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', getReport(ME));
            end

            delete(d)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnalise: RFDataHub';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.Tab1_titleGrid = uigridlayout(app.LeftPanelGrid);
            app.Tab1_titleGrid.Tag = 'COLORLOCKED';
            app.Tab1_titleLabel = uilabel(app.Tab1_titleGrid);
            app.Tab1_titleLabel.Text = 'ASPECTOS GERAIS';
            app.Tab1_titleImage = uiimage(app.Tab1_titleGrid);
            app.Tab1_titleImage.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_titleImage.ImageSource = 'LT_Dots2.png';
            app.Tab1_infoGrid = uigridlayout(app.LeftPanelGrid);
            app.Description = uilabel(app.Tab1_infoGrid);
            app.Description.Interpreter = 'html';
            app.Description.Text = '<p style="text-align: justify; line-height: 1; margin-right: 2px;">O <b>RFDataHub</b> é um <i>pipeline</i> de dados de estações de telecomunicações, formado por registros extraídos de bases de dados do MOSAICO, STEL, SRD, ICAO, AISWEB, GEOAIS e REDEMET. Os dados apresentados aqui foram consolidados em <b>%s</b>.</font>';
            app.layout_Label = uilabel(app.Tab1_infoGrid);
            app.layout_Label.Text = 'Layout';
            app.layout_Grid = uigridlayout(app.Tab1_infoGrid);
            app.layout_mapButton = ccTools.Button(app.layout_Grid);
            app.layout_mapButton.Text = 'Mapa';
            app.layout_mapButton.Description = 'Visualização georeferenciada das estações';
            app.layout_mapButton.Icon = 'Instr_Global.png';
            app.layout_mapButton.HorizontalAlign = 'left';
            app.layout_mapButton.VerticalAlign = 'top';
            app.layout_mapButton.BorderRadius = '0px';
            app.layout_mapButton.BorderPadding = 3;
            app.layout_mapButton.IconWidth = 20;
            app.layout_mapButton.IconHeight = 20;
            app.layout_mapButton.tFontWeight = 'normal';
            app.layout_mapButton.tFontSize = 11;
            app.layout_mapButton.dFontSize = 9;
            app.layout_mapButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_histogramButton = ccTools.Button(app.layout_Grid);
            app.layout_histogramButton.Text = 'Histograma';
            app.layout_histogramButton.Description = 'Frequência & Serviço';
            app.layout_histogramButton.Icon = 'play_Truncated.png';
            app.layout_histogramButton.HorizontalAlign = 'left';
            app.layout_histogramButton.VerticalAlign = 'top';
            app.layout_histogramButton.BorderRadius = '0px';
            app.layout_histogramButton.BorderPadding = 3;
            app.layout_histogramButton.IconWidth = 20;
            app.layout_histogramButton.IconHeight = 20;
            app.layout_histogramButton.tFontWeight = 'normal';
            app.layout_histogramButton.tFontSize = 11;
            app.layout_histogramButton.dFontSize = 9;
            app.layout_histogramButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_tableButton = ccTools.Button(app.layout_Grid);
            app.layout_tableButton.Text = 'Tabela';
            app.layout_tableButton.Description = 'Recorte do RFDataHub';
            app.layout_tableButton.Icon = 'LT_Channel.png';
            app.layout_tableButton.HorizontalAlign = 'left';
            app.layout_tableButton.VerticalAlign = 'top';
            app.layout_tableButton.BorderRadius = '0px';
            app.layout_tableButton.BorderPadding = 3;
            app.layout_tableButton.IconWidth = 22;
            app.layout_tableButton.IconHeight = 22;
            app.layout_tableButton.tFontWeight = 'normal';
            app.layout_tableButton.tFontSize = 11;
            app.layout_tableButton.dFontSize = 9;
            app.layout_tableButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_pdfButton = ccTools.Button(app.layout_Grid);
            app.layout_pdfButton.Text = 'Relatório canal';
            app.layout_pdfButton.Description = 'Detalhes da outorga, do licenciamento etc';
            app.layout_pdfButton.Icon = 'Publish_PDF_16-69bd9a64885e6016ee3e1ed74cc2597d.png';
            app.layout_pdfButton.HorizontalAlign = 'left';
            app.layout_pdfButton.VerticalAlign = 'top';
            app.layout_pdfButton.BorderRadius = '0px';
            app.layout_pdfButton.BorderPadding = 3;
            app.layout_pdfButton.IconWidth = 17;
            app.layout_pdfButton.IconHeight = 17;
            app.layout_pdfButton.tFontWeight = 'normal';
            app.layout_pdfButton.tFontSize = 11;
            app.layout_pdfButton.dFontSize = 9;
            app.layout_pdfButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.points_Label = uilabel(app.Tab1_infoGrid);
            app.points_Label.Text = 'Pontos de interesse';
            app.points_refreshGrid = uigridlayout(app.Tab1_infoGrid);
            app.points_refreshImage = uiimage(app.points_refreshGrid);
            app.points_refreshImage.ImageClickedFcn = createCallbackFcn(app, @points_refreshImageClicked, true);
            app.points_refreshImage.ImageSource = 'LT_refresh.png';
            app.points_Tree = uitree(app.Tab1_infoGrid);
            app.points_Tree.SelectionChangedFcn = createCallbackFcn(app, @points_TreeSelectionChanged, true);
            app.points_Panel = uipanel(app.Tab1_infoGrid);
            app.points_Grid = uigridlayout(app.points_Panel);
            app.points_LatitudeLabel = uilabel(app.points_Grid);
            app.points_LatitudeLabel.Text = 'Latitude:';
            app.points_Latitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Latitude.ValueDisplayFormat = '%.6f';
            app.points_Latitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Latitude.Editable = 'off';
            app.points_Latitude.Value = -1;
            app.points_LongitudeLabel = uilabel(app.points_Grid);
            app.points_LongitudeLabel.Text = 'Longitude:';
            app.points_Longitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Longitude.ValueDisplayFormat = '%.6f';
            app.points_Longitude.ValueChangedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_Longitude.Editable = 'off';
            app.points_Longitude.Value = -1;
            app.points_editButton = uibutton(app.points_Grid, 'push');
            app.points_editButton.ButtonPushedFcn = createCallbackFcn(app, @points_editButtonPushed, true);
            app.points_editButton.Icon = 'LT_edit.png';
            app.points_editButton.Text = '';
            app.Tab2_titleGrid = uigridlayout(app.LeftPanelGrid);
            app.Tab2_titleGrid.Tag = 'COLORLOCKED';
            app.Tab2_titleLabel = uilabel(app.Tab2_titleGrid);
            app.Tab2_titleLabel.Text = 'FILTROS';
            app.Tab2_titleImage = uiimage(app.Tab2_titleGrid);
            app.Tab2_titleImage.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_titleImage.ImageSource = 'LT_Filter.png';
            app.Tab2_infoGrid = uigridlayout(app.LeftPanelGrid);
            app.filter_TypePanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_TypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_TypeRadioButton1 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton1.Tag = '16';
            app.filter_TypeRadioButton1.Text = 'Fonte';
            app.filter_TypeRadioButton1.Value = true;
            app.filter_TypeRadioButton2 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton2.Tag = '1';
            app.filter_TypeRadioButton2.Text = 'Frequência';
            app.filter_TypeRadioButton3 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton3.Tag = '13';
            app.filter_TypeRadioButton3.Text = 'Largura banda';
            app.filter_TypeRadioButton4 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton4.Tag = '12';
            app.filter_TypeRadioButton4.Text = 'Classe emissão';
            app.filter_TypeRadioButton5 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton5.Tag = '2';
            app.filter_TypeRadioButton5.Text = 'Entidade';
            app.filter_TypeRadioButton6 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton6.Tag = '3';
            app.filter_TypeRadioButton6.Text = 'Fistel';
            app.filter_TypeRadioButton7 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton7.Tag = '4';
            app.filter_TypeRadioButton7.Text = 'Serviço';
            app.filter_TypeRadioButton8 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton8.Tag = '5';
            app.filter_TypeRadioButton8.Text = 'Estação';
            app.filter_TypeRadioButton9 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton9.Tag = '10';
            app.filter_TypeRadioButton9.Text = 'UF';
            app.filter_TypeRadioButton10 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton10.Tag = '9';
            app.filter_TypeRadioButton10.Text = 'Município';
            app.filter_TypeRadioButton11 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton11.Tag = '-1';
            app.filter_TypeRadioButton11.Text = 'Distância';
            app.filter_TypeRadioButton12 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton12.Tag = '-1';
            app.filter_TypeRadioButton12.Text = 'ROI';
            app.filter_operationPanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_operationPanel.SelectionChangedFcn = createCallbackFcn(app, @filter_operationPanelSelectionChanged, true);
            app.filter_operationButton1 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton1.Tag = 'numeric+textFree+textList';
            app.filter_operationButton1.Tooltip = {'Igual'};
            app.filter_operationButton1.Text = '=';
            app.filter_operationButton1.Value = true;
            app.filter_operationButton2 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton2.Tag = 'numeric+textFree+textList';
            app.filter_operationButton2.Tooltip = {'Diferente'};
            app.filter_operationButton2.Text = '≠';
            app.filter_operationButton3 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton3.Tag = 'ROI+textFree';
            app.filter_operationButton3.Tooltip = {'Contém'};
            app.filter_operationButton3.Text = 'ꓚ';
            app.filter_operationButton4 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton4.Tag = 'numeric';
            app.filter_operationButton4.Tooltip = {'Menor'};
            app.filter_operationButton4.Text = '<';
            app.filter_operationButton5 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton5.Tag = 'numeric';
            app.filter_operationButton5.Tooltip = {'Menor ou igual'};
            app.filter_operationButton5.Text = '≤';
            app.filter_operationButton6 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton6.Tag = 'numeric';
            app.filter_operationButton6.Tooltip = {'Maior'};
            app.filter_operationButton6.Text = '>';
            app.filter_operationButton7 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton7.Tag = 'numeric';
            app.filter_operationButton7.Tooltip = {'Maior ou igual'};
            app.filter_operationButton7.Text = '≥';
            app.filter_operationButton8 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton8.Tag = 'numeric';
            app.filter_operationButton8.Tooltip = {'Dentro do intervalo'};
            app.filter_operationButton8.Text = '><';
            app.filter_operationButton9 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton9.Tag = 'numeric';
            app.filter_operationButton9.Tooltip = {'Fora do intervalo'};
            app.filter_operationButton9.Text = '<>';
            app.filter_operationValuesPanel = uipanel(app.filter_operationPanel);
            app.filter_operationValuesGrid = uigridlayout(app.filter_operationValuesPanel);
            app.filter_operationNumValue1 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue1.Limits = [-1 Inf];
            app.filter_operationNumValue1.Value = -1;
            app.filter_operationSeparator = uilabel(app.filter_operationValuesGrid);
            app.filter_operationSeparator.Text = '-';
            app.filter_operationNumValue2 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue2.Limits = [-1 Inf];
            app.filter_operationNumValue2.Value = -1;
            app.filter_operationTextFreeValue = uieditfield(app.filter_operationValuesGrid, 'text');
            app.filter_operationTextListValue = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationTextListValue.Items = {};
            app.filter_operationTextListValue.Value = {};
            app.filter_operationLogicalReferenceLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalReferenceLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_operationLogicalReference = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogicalReference.Items = {};
            app.filter_operationLogicalReference.ValueChangedFcn = createCallbackFcn(app, @filter_operationLogicalReferenceValueChanged, true);
            app.filter_operationLogicalReference.Value = {};
            app.filter_operationLogicalLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalLabel.Text = {'Operador '; 'lógico:'};
            app.filter_operationLogical = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogical.Items = {'E (&&)'};
            app.filter_operationLogical.Value = 'E (&&)';
            app.filter_addImage = uiimage(app.Tab2_infoGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_addImage.Tooltip = {'Adicionar filtro'};
            app.filter_addImage.ImageSource = 'LT_Add.png';
            app.filter_Tree = uitree(app.Tab2_infoGrid, 'checkbox');
            % Assign Checked Nodes
            app.filter_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_TreeCheckedNodesChanged, true);
            app.filter_nRows = uilabel(app.Tab2_infoGrid);
            app.filter_nRows.Text = '0 DE 0 REGISTROS';
            app.Tab3_titleGrid = uigridlayout(app.LeftPanelGrid);
            app.Tab3_titleGrid.Tag = 'COLORLOCKED';
            app.Tab3_titleLabel = uilabel(app.Tab3_titleGrid);
            app.Tab3_titleLabel.Text = 'MISCELÂNEAS';
            app.Tab3_titleImage = uiimage(app.Tab3_titleGrid);
            app.Tab3_titleImage.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab3_titleImage.ImageSource = 'LT_misc.png';
            app.Tab3_infoGrid = uigridlayout(app.LeftPanelGrid);
            app.misc_OperationLabel = uilabel(app.Tab3_infoGrid);
            app.misc_OperationLabel.Text = 'Operações';
            app.misc_OperationPanel = uipanel(app.Tab3_infoGrid);
            app.misc_OperationGrid = uigridlayout(app.misc_OperationPanel);
            app.misc_ExportButton = uibutton(app.misc_OperationGrid, 'push');
            app.misc_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @misc_exportButtonPushed, true);
            app.misc_ExportButton.Icon = 'Export_24.png';
            app.misc_ExportButton.Text = '';
            app.misc_ExportLabel = uilabel(app.misc_OperationGrid);
            app.misc_ExportLabel.Text = {'Exportar'; 'tabela'};
            app.misc_PDFButton = uibutton(app.misc_OperationGrid, 'push');
            app.misc_PDFButton.ButtonPushedFcn = createCallbackFcn(app, @misc_PDFButtonButtonPushed, true);
            app.misc_PDFButton.Icon = 'Publish_PDF_16-69bd9a64885e6016ee3e1ed74cc2597d.png';
            app.misc_PDFButton.Text = '';
            app.misc_PDFLabel = uilabel(app.misc_OperationGrid);
            app.misc_PDFLabel.Text = {'Relatório'; 'canal'};
            app.misc_SelectedStationLabel = uilabel(app.Tab3_infoGrid);
            app.misc_SelectedStationLabel.Text = 'Estação de telecomunicações selecionada';
            app.misc_SelectedStationPanel = uipanel(app.Tab3_infoGrid);
            app.misc_SelectedStationGrid = uigridlayout(app.misc_SelectedStationPanel);
            app.misc_SelectedStation = uihtml(app.misc_SelectedStationGrid);
            app.misc_SelectedStation.HTMLSource = ' ';
            app.misc_Clipboard = uibutton(app.Tab3_infoGrid, 'push');
            app.misc_Clipboard.Icon = 'LT_copy.png';
            app.misc_Clipboard.Enable = 'off';
            app.misc_Clipboard.Text = '';
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @PanelTab_VisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
            app.Document = uigridlayout(app.Grid);
            app.plot_Panel1 = uipanel(app.Document);
            app.plot_Panel2 = uipanel(app.Document);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'ID'; 'Frequência|(MHz)'; 'Latitude'; 'Longitude'; 'Descrição|(Entidade+Fistel+Multiplicidade+Localidade)'; 'Serviço'; 'Estação'; 'Classe|emissão'; 'Largura|(kHz)'; 'URL'; 'Distância|(km)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false false false false false false true];
            app.UITable.Multiselect = 'off';
            app.chReportGrid = uigridlayout(app.Document);
            app.chReportHTML = uihtml(app.chReportGrid);
            app.chReportHTML.HTMLSource = 'pdfLogo.html';
            app.chReportLabel = uilabel(app.chReportGrid);
            app.chReportLabel.Text = 'Estação nº XXX';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu_delButton = uimenu(app.ContextMenu);
            app.ContextMenu_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.ContextMenu_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFDataHub(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end