classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout22            matlab.ui.container.GridLayout
        GridLayout23            matlab.ui.container.GridLayout
        Panel_17                matlab.ui.container.Panel
        GridLayout24_5          matlab.ui.container.GridLayout
        FilesNumberLabel_6      matlab.ui.control.Label
        FilesNumber_6           matlab.ui.control.Label
        Panel_14                matlab.ui.container.Panel
        GridLayout24_3          matlab.ui.container.GridLayout
        FilesNumberLabel_4      matlab.ui.control.Label
        FilesNumber_4           matlab.ui.control.Label
        Panel_15                matlab.ui.container.Panel
        GridLayout30            matlab.ui.container.GridLayout
        ROHDESCHWARZEB500101512002V04904072871000Label  matlab.ui.control.Label
        Subtitle                matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        Panel_11                matlab.ui.container.Panel
        GridLayout24            matlab.ui.container.GridLayout
        FilesNumberLabel        matlab.ui.control.Label
        FilesNumber             matlab.ui.control.Label
        Panel_13                matlab.ui.container.Panel
        GridLayout24_2          matlab.ui.container.GridLayout
        FilesNumberLabel_3      matlab.ui.control.Label
        FilesNumber_3           matlab.ui.control.Label
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        GridLayout18            matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        GridLayout29            matlab.ui.container.GridLayout
        TipoDropDown            matlab.ui.control.DropDown
        TipoDropDownLabel       matlab.ui.control.Label
        NodeOrder_Down          matlab.ui.control.Image
        NodeOrder_Up            matlab.ui.control.Image
        GridLayout28            matlab.ui.container.GridLayout
        Parmetro1DropDown       matlab.ui.control.DropDown
        Parmetro1DropDownLabel  matlab.ui.control.Label
        Parmetro2DropDown       matlab.ui.control.DropDown
        Parmetro2DropDownLabel  matlab.ui.control.Label
        HabilitarCheckBox       matlab.ui.control.CheckBox
        GridLayout26            matlab.ui.container.GridLayout
        PercentilFactor         matlab.ui.control.Spinner
        PercentilFactorLabel    matlab.ui.control.Label
        BinValue                matlab.ui.control.DropDown
        BinValueLabel           matlab.ui.control.Label
        BinLengthLabel          matlab.ui.control.Label
        BinLength               matlab.ui.control.NumericEditField
        Button                  matlab.ui.control.Button
        GridLayout27            matlab.ui.container.GridLayout
        FreqCenterLabel         matlab.ui.control.Label
        FreqCenter              matlab.ui.control.NumericEditField
        BW                      matlab.ui.control.NumericEditField
        BWLabel                 matlab.ui.control.Label
        Logo                    matlab.ui.control.Image
        Tree                    matlab.ui.container.Tree
        Filter_Channel          matlab.ui.container.TreeNode
        Filter_DataBinning      matlab.ui.container.TreeNode
        MAPANode                matlab.ui.container.TreeNode
        PLOTNode                matlab.ui.container.TreeNode
        ROTANode                matlab.ui.container.TreeNode
        SCATTERNode             matlab.ui.container.TreeNode
        DENSIDADENode           matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        
        CallingApp
        
        repoData
        Filters

        UIAxes1
        
    end
    
    methods (Access = private)

        function Fcn_StartUp(app)

            if height(app.Table.Data)
                % UF & Node filter
                set(app.UF,   'Items', [''; unique(app.Table.Data.Site_State_Code)], 'Value', '')
                set(app.City, 'Items', {''}, 'Enable', 0);

                app.ROTANode.Items = unique(app.Table.Data.Equipment_ID);
                
                % Band filter
                app.FreqCenter.Value = min(app.Table.Data.Start_Frequency);
                app.BW.Value  = max(app.Table.Data.Stop_Frequency);
    
                % Observation time filter
                firstTimeStamp = min(app.Table.Data.Initial_Time);
                lastTimeStamp  = max(app.Table.Data.Final_Time);
    
                app.DatePicker1.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker1, 'Value', firstTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
                
                app.DatePicker2.Limits = [datetime([0 1 1]), datetime([9999 12 31])];
                set(app.DatePicker2, 'Value', lastTimeStamp, 'Limits', [firstTimeStamp, lastTimeStamp])
   
                app.FilesNumber.Text = num2str(numel(unique(app.Table.Data.Filename)));
            else
                app.FilesNumber.Text = '0';
            end
    
            app.Spinner1.Value = 0;
            app.Spinner2.Value = 0;
            app.Spinner3.Value = 23;
            app.Spinner4.Value = 59;

            Fcn_FilterPanel(app)
            
        end


        function ROIEvents(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    disableDefaultInteractivity(app.axes1)
                    
%                     ind1 = app.traceInfo.SelectedNode;
                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    
                    app.Playback_peakCF.Value               = round(app.line_Mkr.Position(1), 3);
                    app.line_ClearWrite.MarkerIndices(ind2) = round((app.Playback_peakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    % Se tiver apenas um marcador, então a string fica como
                    % cell. Caso tenha mais de um marcador, então a string
                    % fica como char.
                    markerTag = findobj('Type', 'Text', 'String', sprintf('  %d', ind2));
                    if isempty(markerTag)
                        markerTag = findobj('Type', 'Text', 'String', {sprintf('  %d', ind2)});
                    end
                    markerTag.Position(1:2) = [app.Playback_peakCF.Value, app.line_ClearWrite.YData(app.line_ClearWrite.MarkerIndices(ind2))];
                    
                    set(app.Playback_peakTree.Children(ind2), 'Text', sprintf("%d: %.3f MHz", ind2, app.Playback_peakCF.Value), ...
                                                              'NodeData', ind2)
                    
                case{'ROIMoved'}
                    enableDefaultInteractivity(app.axes1)
                    
                    ind1 = app.traceInfo.SelectedNode;
                    ind2 = app.Playback_peakTree.SelectedNodes(1).NodeData;
                    
                    newIndex = round((app.Playback_peakCF.Value*1e+6 - app.bCoef)/app.aCoef);
                    
                    app.specData(ind1).Emissions(ind2,1:2) = [newIndex, app.Playback_peakCF.Value];
                    app.specData(ind1).Emissions = sortrows(app.specData(ind1).Emissions);
                    
                    selectedEmission = find(app.specData(ind1).Emissions(:,1) == newIndex, 1);
                    Playback_TreeBuilding(app, selectedEmission)
                    Plot_Spectrum(app, ind1, selectedEmission)
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, rawData)
            
            tic
%             app.UIFigure.Visible = 0;

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;

            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)

            % geoAxes
            app.UIAxes1 = geoaxes(app.GridLayout23, 'FontSize', 6, 'Toolbar', [], 'Units', 'pixels');

            app.UIAxes1.Layout.Row    = [3, 5];
            app.UIAxes1.Layout.Column = 1;

            app.UIAxes1.LatitudeLabel.String = '';
            app.UIAxes1.LongitudeLabel.String = '';


            % specAxes
            xData = linspace(rawData.MetaData.FreqStart/1e+6, rawData.MetaData.FreqStop/1e+6, rawData.MetaData.DataPoints);
            
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, xData, rawData.statsData(:, 1), 'Tag', 'Drive-test: MinHold',  'Color', app.CallingApp.General.Colors(1,:));
            plot(app.UIAxes2, xData, rawData.statsData(:, 3), 'Tag', 'Drive-test: MeanHold', 'Color', app.CallingApp.General.Colors(2,:));
            plot(app.UIAxes2, xData, rawData.statsData(:, 4), 'Tag', 'Drive-test: MaxHold',  'Color', app.CallingApp.General.Colors(3,:));
            hold(app.UIAxes2, 'off')

%             set(app.UIAxes2, 'Interactions', [])


            % histogramAxes
            hold(app.UIAxes3, 'on')
            histogram(app.UIAxes3, rawData.Data{2}, NumBins=64, Normalization='probability', EdgeColor=[.93, .69, .13])
            app.UIAxes3.YAxis.Exponent = -2;

            hold(app.UIAxes3, 'off')
            
%             set(app.UIAxes3, 'Interactions', [])
            drawnow nocallbacks


            % ROI no eixo do espectro...
            roi = images.roi.Rectangle(app.UIAxes2, Position = [mean([xData(1), xData(end)]), app.UIAxes2.YLim(1), 1, diff(app.UIAxes2.YLim)], ...
                                                    Color = [0.40,0.73,0.88], MarkerSize = 4, Deletable = 0, LineWidth = .5, Tag='Drive-test: ROI');
            addlistener(roi,'MovingROI', @app.ROIEvents);
            addlistener(roi,'ROIMoved',  @app.ROIEvents);


            % Leitura de dados de teste...
            Drive = Argus_DriveTest('Energia_b.csv');
            
            hold(app.UIAxes1, 'on')
            geodensityplot(app.UIAxes1, Drive.Latitude, Drive.Longitude, Drive.Level, 'FaceColor', 'interp');
            geoplot(app.UIAxes1, Drive.Latitude, Drive.Longitude)
            
%             idx = find(Drive.Level > max(Drive.Level));
%             geoscatter(app.UIAxes1, Drive.Latitude(idx), Drive.Longitude(idx), Drive.Level(idx), 'filled', Marker='hexagram')
            hold(app.UIAxes1, 'off')


            % Visibilidade...
            while ~isequal(app.UIAxes1.InnerPosition, [ 44,  34, 830, 574])       
                pause(5)
                app.UIAxes1.InnerPosition = [ 44,  34, 830, 574];
                drawnow nocallbacks
            end
            toc

            app.UIFigure.Visible = 1;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin2 = [];
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            app.NodeOrder_Down.Enable = 0;
            app.NodeOrder_Up.Enable   = 0;

            selectedNodes = app.Tree.SelectedNodes;
            
            switch selectedNodes.NodeData
                case 1; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1, 93,   0,  0, 0, 1, 24};
                case 2; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1,  0, 142,  0, 0, 1, 24};
                case 3; app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1,  0,   0, 44, 0, 1, 24};
                
                otherwise
                    if selectedNodes.NodeData == 4
                        app.Tree.SelectedNodes = app.Tree.Children(4).Children(1);
                    end
                    app.GridLayout18.RowHeight = {77, 1, '1x', 15, 1, 0, 0, 0, 115, 1, 24};

                    app.NodeOrder_Down.Enable = 1;
                    app.NodeOrder_Up.Enable   = 1;
            end

            expand(app.Tree, 'all')
            
        end

        % Value changed function: BinValue
        function BinValueValueChanged(app, event)
            
            if strcmp(app.BinValue.Value, 'Percentil')
                app.PercentilFactor.Enable      = 1;
                app.PercentilFactorLabel.Enable = 1;
            else
                app.PercentilFactor.Enable      = 0;
                app.PercentilFactorLabel.Enable = 0;
            end

        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            
            geobasemap(app.UIAxes1, app.TipoDropDown.Value)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            app.UIAxes1.InnerPosition = [ 44,  34, 830, 574];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Drive-test';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout22 = uigridlayout(app.UIFigure);
            app.GridLayout18 = uigridlayout(app.GridLayout22);
            app.Tree = uitree(app.GridLayout18);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Filter_Channel = uitreenode(app.Tree);
            app.Filter_Channel.NodeData = 1;
            app.Filter_Channel.Text = 'CANAL';
            app.Filter_DataBinning = uitreenode(app.Tree);
            app.Filter_DataBinning.NodeData = 2;
            app.Filter_DataBinning.Text = 'DATA BINNING';
            app.MAPANode = uitreenode(app.Tree);
            app.MAPANode.NodeData = 3;
            app.MAPANode.Text = 'MAPA';
            app.PLOTNode = uitreenode(app.Tree);
            app.PLOTNode.NodeData = 4;
            app.PLOTNode.Text = 'PLOT';
            app.ROTANode = uitreenode(app.PLOTNode);
            app.ROTANode.NodeData = 5;
            app.ROTANode.Icon = 'mainMenu_Playback.png';
            app.ROTANode.Text = 'ROTA';
            app.SCATTERNode = uitreenode(app.PLOTNode);
            app.SCATTERNode.NodeData = 6;
            app.SCATTERNode.Icon = 'TruncatedPeak.png';
            app.SCATTERNode.Text = 'SCATTER';
            app.DENSIDADENode = uitreenode(app.PLOTNode);
            app.DENSIDADENode.NodeData = 7;
            app.DENSIDADENode.Icon = 'heatmap.PNG';
            app.DENSIDADENode.Text = 'DENSIDADE';
            app.Logo = uiimage(app.GridLayout18);
            app.Logo.ImageSource = 'Puzzle.png';
            app.GridLayout27 = uigridlayout(app.GridLayout18);
            app.BWLabel = uilabel(app.GridLayout27);
            app.BWLabel.Text = 'Largura (kHz):';
            app.BW = uieditfield(app.GridLayout27, 'numeric');
            app.BW.ValueDisplayFormat = '%.6f';
            app.BW.Value = 108;
            app.FreqCenter = uieditfield(app.GridLayout27, 'numeric');
            app.FreqCenter.ValueDisplayFormat = '%.6f';
            app.FreqCenter.Value = 76;
            app.FreqCenterLabel = uilabel(app.GridLayout27);
            app.FreqCenterLabel.Text = 'Frequência centrall (MHz):';
            app.Button = uibutton(app.GridLayout18, 'push');
            app.Button.Icon = 'Filter.png';
            app.Button.Text = '';
            app.GridLayout26 = uigridlayout(app.GridLayout18);
            app.BinLength = uieditfield(app.GridLayout26, 'numeric');
            app.BinLength.ValueDisplayFormat = '%.6f';
            app.BinLength.Value = 100;
            app.BinLengthLabel = uilabel(app.GridLayout26);
            app.BinLengthLabel.Text = 'Comprimento (metros):';
            app.BinValueLabel = uilabel(app.GridLayout26);
            app.BinValueLabel.Text = 'Amostra significativa:';
            app.BinValue = uidropdown(app.GridLayout26);
            app.BinValue.Items = {'Mínima', 'RMS', 'Média', 'Mediana', 'Máxima', 'Percentil'};
            app.BinValue.ValueChangedFcn = createCallbackFcn(app, @BinValueValueChanged, true);
            app.BinValue.Value = 'Mediana';
            app.PercentilFactorLabel = uilabel(app.GridLayout26);
            app.PercentilFactorLabel.Enable = 'off';
            app.PercentilFactorLabel.Text = 'Fator:';
            app.PercentilFactor = uispinner(app.GridLayout26);
            app.PercentilFactor.Step = 10;
            app.PercentilFactor.Limits = [0 100];
            app.PercentilFactor.RoundFractionalValues = 'on';
            app.PercentilFactor.ValueDisplayFormat = '%.0f';
            app.PercentilFactor.Enable = 'off';
            app.PercentilFactor.Value = 10;
            app.GridLayout28 = uigridlayout(app.GridLayout18);
            app.HabilitarCheckBox = uicheckbox(app.GridLayout28);
            app.HabilitarCheckBox.Text = 'Habilitar';
            app.Parmetro2DropDownLabel = uilabel(app.GridLayout28);
            app.Parmetro2DropDownLabel.Text = 'Parâmetro 2:';
            app.Parmetro2DropDown = uidropdown(app.GridLayout28);
            app.Parmetro1DropDownLabel = uilabel(app.GridLayout28);
            app.Parmetro1DropDownLabel.Text = 'Parâmetro 1:';
            app.Parmetro1DropDown = uidropdown(app.GridLayout28);
            app.NodeOrder_Up = uiimage(app.GridLayout18);
            app.NodeOrder_Up.Enable = 'off';
            app.NodeOrder_Up.ImageSource = 'General_Colapse.png';
            app.NodeOrder_Down = uiimage(app.GridLayout18);
            app.NodeOrder_Down.Enable = 'off';
            app.NodeOrder_Down.ImageSource = 'General_Expand.png';
            app.GridLayout29 = uigridlayout(app.GridLayout18);
            app.TipoDropDownLabel = uilabel(app.GridLayout29);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.GridLayout29);
            app.TipoDropDown.Items = {'streets-light', 'streets', 'colorterrain', 'satellite'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'streets-light';
            app.Button_2 = uibutton(app.GridLayout18, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Playback_Icon1.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout18, 'push');
            app.Button_3.Icon = 'TruncatedPeak.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout18, 'push');
            app.Button_4.Icon = 'Instr_KeySight.png';
            app.Button_4.Text = '';
            app.Image = uiimage(app.GridLayout18);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'Line.png';
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.UIAxes2 = uiaxes(app.GridLayout23);
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Nível (dBm)')
            app.UIAxes3 = uiaxes(app.GridLayout23);
            xlabel(app.UIAxes3, 'Nível (dBm)')
            ylabel(app.UIAxes3, 'Amostras (%)')
            app.Panel_13 = uipanel(app.GridLayout23);
            app.GridLayout24_2 = uigridlayout(app.Panel_13);
            app.FilesNumber_3 = uilabel(app.GridLayout24_2);
            app.FilesNumber_3.Text = 'AMOSTRAS';
            app.FilesNumberLabel_3 = uilabel(app.GridLayout24_2);
            app.FilesNumberLabel_3.Text = '26890';
            app.Panel_11 = uipanel(app.GridLayout23);
            app.GridLayout24 = uigridlayout(app.Panel_11);
            app.FilesNumber = uilabel(app.GridLayout24);
            app.FilesNumber.Text = 'VELOCIDADE';
            app.FilesNumberLabel = uilabel(app.GridLayout24);
            app.FilesNumberLabel.Text = '38 km/h';
            app.Title = uilabel(app.GridLayout23);
            app.Title.Text = 'Drive-test';
            app.Subtitle = uilabel(app.GridLayout23);
            app.Subtitle.Text = 'Um texto qualquer...';
            app.Panel_15 = uipanel(app.GridLayout23);
            app.GridLayout30 = uigridlayout(app.Panel_15);
            app.ROHDESCHWARZEB500101512002V04904072871000Label = uilabel(app.GridLayout30);
            app.ROHDESCHWARZEB500101512002V04904072871000Label.Text = 'ROHDE&SCHWARZ,EB500,101.512/002,V04.90-4072.8710.00';
            app.Panel_14 = uipanel(app.GridLayout23);
            app.GridLayout24_3 = uigridlayout(app.Panel_14);
            app.FilesNumber_4 = uilabel(app.GridLayout24_3);
            app.FilesNumber_4.Text = 'REVISITA';
            app.FilesNumberLabel_4 = uilabel(app.GridLayout24_3);
            app.FilesNumberLabel_4.Text = '1s';
            app.Panel_17 = uipanel(app.GridLayout23);
            app.GridLayout24_5 = uigridlayout(app.Panel_17);
            app.FilesNumber_6 = uilabel(app.GridLayout24_5);
            app.FilesNumber_6.Text = 'CANAL';
            app.FilesNumberLabel_6 = uilabel(app.GridLayout24_5);
            app.FilesNumberLabel_6.Text = '104.500 MHz @ 200.000 kHz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end