classdef winTemplate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Grid1           matlab.ui.container.GridLayout
        GridLayout6     matlab.ui.container.GridLayout
        Warn            matlab.ui.control.Image
        ModelLabel      matlab.ui.control.Label
        Sublabel2       matlab.ui.control.Label
        Panel           matlab.ui.container.Panel
        GridLayout2_2   matlab.ui.container.GridLayout
        Sublabel1       matlab.ui.control.Label
        GridLayout5     matlab.ui.container.GridLayout
        GridLayout2     matlab.ui.container.GridLayout
        HTML            matlab.ui.control.HTML
        GridLayout3     matlab.ui.container.GridLayout
        GridLayout4     matlab.ui.container.GridLayout
        FreeText        matlab.ui.control.TextArea
        ContentLabel    matlab.ui.control.Label
        Grid1B          matlab.ui.container.GridLayout
        Button_open_2   matlab.ui.control.Button
        Image_2         matlab.ui.control.Image
        Image           matlab.ui.control.Image
        Button_save     matlab.ui.control.Button
        Button_open     matlab.ui.control.Button
        Button_new      matlab.ui.control.Button
        Button_del      matlab.ui.control.Button
        Button_preview  matlab.ui.control.Button
        Button_edit     matlab.ui.control.StateButton
        GridLayout      matlab.ui.container.GridLayout
        Image_up        matlab.ui.control.Image
        Image_down      matlab.ui.control.Image
        Image_add       matlab.ui.control.Image
        Tree            matlab.ui.container.Tree
        Model           matlab.ui.control.DropDown
        Logo            matlab.ui.control.Image
        Title           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        ModelInfo
        Template

        i = ["OBJETIVO",    "mainMenu_Home.png"; ...
             "INTRODUÇÃO",  "Toolbar_Icon3.png"; ...
             "METODOLOGIA", "Filter.png"; ...
             "RESULTADOS",  "1200px-BellcurveMean_Edited2.png"; ...
             "ANÁLISE",     "TruncatedPeak.png"; ...
             "CONCLUSÃO",   "Pin.png"]
        
    end
    
    methods (Access = private)

        function Fcn_ReadModels(app)

            app.ModelInfo   = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', 'InspectionType.cfg'))));
            app.Model.Items = app.ModelInfo.Name;

        end


        function Fcn_ReadTemplate(app)

            idx = find(strcmp(app.ModelInfo.Name, app.Model.Value));
            
            app.Sublabel1.Text = upper(app.ModelInfo.Name{idx});
            app.Sublabel2.Text = sprintf('v. %s (%s)\n%s', app.ModelInfo.Version{idx}, ...
                                                           app.ModelInfo.Release{idx}, ...
                                                           app.ModelInfo.Author{idx});

            app.Template = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'DataBase', app.ModelInfo.Template{idx}))));


            Fcn_TreeBuilding(app)

        end


        function Fcn_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end
         
            for ii = 1:height(app.Template)
                idx = find(app.i(:,1) == app.Template.Data(ii).String);
                
                Parent = uitreenode(app.Tree, 'Text', sprintf('ITEM %.0f: %s', ii, app.Template.Data(ii).String), ...
                                              'NodeData', ii, 'Icon', app.i(idx,2));

                for jj = 1:numel(app.Template.Data(ii).Children)
                    uitreenode(Parent, 'Text', app.Template.Data(ii).Children(jj).Type, ...
                                       'NodeData', ii);
                end
            end
            expand(app.Tree, 'all')

            if ~isempty(app.Tree.Children)
                app.Tree.SelectedNodes = app.Tree.Children(1);
                TreeSelectionChanged(app)
            end

        end

        
        function Fcn_Layout(app, Mode)

            switch Mode
                case 'Edit'
                    if app.Button_edit.Value
                        app.Button_del.Enable  = 0;

                        app.Image_up.Enable   = 0;
                        app.Image_down.Enable = 0;
                    else
                        app.Button_del.Enable  = 1;
                    end

                case 'TreeSelectionChanged'
                    app.Button_edit.Enable = 1;
                    app.Button_del.Enable  = 1;
            end
            Fcn_Layout_UpDown(app)
            
        end


        function Fcn_Layout_UpDown(app)

            Parent = app.Tree.SelectedNodes.Parent;
            
            NN   = numel(Parent.Children);
            idx1 = find([Parent.Children.NodeData] == Parent.Children(1).NodeData);
            idx2 = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);

            if NN == 1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 0;

            elseif idx2 == idx1
                app.Image_up.Enable   = 0;
                app.Image_down.Enable = 1;

            elseif idx2 - idx1 + 1 == NN
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 0;

            else
                app.Image_up.Enable   = 1;
                app.Image_down.Enable = 1;
            end

        end
        

        function Fcn_NodeData(app)

            for ii = 1:numel(app.Tree.Children)
                idx = app.Tree.Children(ii).UserData;

                set(app.Tree.Children(ii), 'Text', sprintf('ITEM %.0f: %s', ii, app.Template.Data{idx}.String), ...
                                           'NodeData', ii)

                for jj = 1:numel(app.Tree.Children(ii).Children)
                    app.Tree.Children(ii).Children(jj).NodeData = ii+jj;
                end
            end
            app.Warn.Visible = 1;

            TreeSelectionChanged(app)

        end

        
        function Fcn_updateValues(app)

            idx = app.Tree.SelectedNodes.UserData;
            app.FreeText.Value = jsonencode(app.Template.Data{idx}, 'PrettyPrint', true);

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.MainApp, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.MainApp, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.MainApp, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Icon3.png'))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            Fcn_ReadModels(app)
            Fcn_ReadTemplate(app)
            Button_previewPushed(app)

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin7 = [];
            delete(app)
            
        end

        % Value changed function: Model
        function ModelValueChanged(app, event)
            
            Fcn_ReadTemplate(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                Fcn_updateValues(app)
            end

            Fcn_Layout(app, 'TreeSelectionChanged')

        end

        % Image clicked function: Image_down, Image_up
        function Image_orderClicked(app, event)
            
            Parent = app.Tree.SelectedNodes.Parent;
            
            idx = find([Parent.Children.NodeData] == app.Tree.SelectedNodes.NodeData);
            switch event.Source
                case app.Image_down
                    Parent.Children(idx:idx+1) = flip(Parent.Children(idx:idx+1));
                    app.Tree.SelectedNodes       = Parent.Children(idx+1);

                case app.Image_up
                    Parent.Children(idx-1:idx) = flip(Parent.Children(idx-1:idx));
                    app.Tree.SelectedNodes       = Parent.Children(idx-1);
            end

            Fcn_NodeData(app)
            
        end

        % Image clicked function: Image_add
        function Image_addClicked(app, event)
            
            if app.Type.Value == "Item"
                value = app.DropDownText.Value;
            else
                value = deblank(strjoin(app.FreeText.Value));
            end

            if ~isempty(value)
                if app.Button_edit.Value
                    idx = app.Tree.SelectedNodes.NodeData;
                    app.Tree.Children(idx).Text = jsonencode(struct('Type',    app.Type.Value,     ...
                                                                   'Editable', app.Editable.Value, ...
                                                                   'String',   value));

                    app.Button_edit.Value = 0;
                else
                    idx = numel(app.Tree.Children)+1;
                    uitreenode(app.Tree, 'Text', jsonencode(struct('Type',     app.Type.Value,     ...
                                                                   'Editable', app.Editable.Value, ...
                                                                   'String',   value)),            ...
                                         'NodeData', idx);
                end
                TreeSelectionChanged(app)

            else
                uialert(app.UIFigure, 'O campo "Conteúdo textual" não pode ficar vazio.', 'appAnálise', 'Icon', 'warning')
            end

        end

        % Value changed function: Button_edit
        function Button_editPushed(app, event)
            
            Fcn_Layout(app, 'Edit')
            Fcn_updateValues(app)

        end

        % Button pushed function: Button_del
        function Button_delPushed(app, event)
            
            if ~isempty(app.Tree.SelectedNodes)
                idx = app.Tree.SelectedNodes.NodeData;
                delete(app.Tree.Children(idx));

                if ~isempty(app.Tree.Children)
                    app.Tree.SelectedNodes = app.Tree.Children(1);
                end

                Fcn_NodeData(app)
            end

        end

        % Button pushed function: Button_preview
        function Button_previewPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento criação do modelo do relatório...");

            try
                htmlContent = sprintf('%s\n\n%s', fileread(fullfile(app.RootFolder, 'Template', 'html_DocumentStyle.txt')), ...
                                                  fileread(fullfile(app.RootFolder, 'Template', 'html_DocumentTableStyle.txt')));
            
                for ii = 1:numel(app.Tree.Children)
                    idx = app.Tree.Children(ii).UserData;

                    htmlContent = sprintf('%s\n\n%s', htmlContent, ReportGenerator_HTML(app.Template(idx,:)));

                    for jj = 1:numel(app.Tree.Children(ii).Children)
                        idx = app.Tree.Children(ii).Children(jj).UserData;

                        switch app.Template.Type{idx}
                            case "Image"
                                arg2 = 1;
                                arg3 = "Image_2022.04.14_T15.59.47_ThreadID300.png";

                            case "Table"
                                arg2 = 3;
                                arg3 = jsondecode(fileread('Report_2022.04.21_T09.39.17_FinalVersion.json'));
                                arg3 = struct2table(arg3.MeasurementData);
                                arg3 = arg3(:, [3,5,15,16]);

                            otherwise
                                arg2 = [];
                                arg3 = [];
                        end

                        htmlContent = sprintf('%s\n\n%s', htmlContent, ReportGenerator_HTML(app.Template(idx,:), {arg2, arg3}));
                    end
                end

                app.HTML.HTMLSource = htmlContent;
                app.Warn.Visible    = 0;

            catch ME
                uialert(app.UIFigure, ME.message, 'appAnálise', 'Icon', 'error')
            end

            delete(d)

        end

        % Button pushed function: Button_open_2
        function Button_open_2Pushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'DataBase', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winTemplate>\n'), sprintf('\n</winTemplate>')));

            Misc_uialertCreation(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="font-size: 24;">Relatório</b>'; '<font>Criação de modelo (Item, parágrafo, lista, tabela e imagem)</font>'; ' '};
            app.Logo = uiimage(app.Grid1);
            app.Logo.ImageSource = 'mainMenu_Report.png';
            app.GridLayout3 = uigridlayout(app.Grid1);
            app.Model = uidropdown(app.GridLayout3);
            app.Model.Items = {'PMEC 2022', 'PMRD 2022', 'PMEF 2022', 'Rádio pirata', 'Drive-test'};
            app.Model.ValueChangedFcn = createCallbackFcn(app, @ModelValueChanged, true);
            app.Model.Value = 'PMEC 2022';
            app.Tree = uitree(app.GridLayout3);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout = uigridlayout(app.GridLayout3);
            app.Image_add = uiimage(app.GridLayout);
            app.Image_add.ImageClickedFcn = createCallbackFcn(app, @Image_addClicked, true);
            app.Image_add.ImageSource = 'General_Add.png';
            app.Image_down = uiimage(app.GridLayout);
            app.Image_down.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_down.Enable = 'off';
            app.Image_down.ImageSource = 'General_Down2.png';
            app.Image_up = uiimage(app.GridLayout);
            app.Image_up.ImageClickedFcn = createCallbackFcn(app, @Image_orderClicked, true);
            app.Image_up.Enable = 'off';
            app.Image_up.ImageSource = 'General_Up2.png';
            app.Grid1B = uigridlayout(app.GridLayout3);
            app.Button_edit = uibutton(app.Grid1B, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editPushed, true);
            app.Button_edit.Enable = 'off';
            app.Button_edit.Icon = 'mainMenu_Edit.png';
            app.Button_edit.Text = '';
            app.Button_preview = uibutton(app.Grid1B, 'push');
            app.Button_preview.ButtonPushedFcn = createCallbackFcn(app, @Button_previewPushed, true);
            app.Button_preview.Icon = 'Toolbar_Zoom.png';
            app.Button_preview.Text = '';
            app.Button_del = uibutton(app.Grid1B, 'push');
            app.Button_del.ButtonPushedFcn = createCallbackFcn(app, @Button_delPushed, true);
            app.Button_del.Icon = 'Report_Icon3.png';
            app.Button_del.Enable = 'off';
            app.Button_del.Text = '';
            app.Button_new = uibutton(app.Grid1B, 'push');
            app.Button_new.Icon = 'Report_Icon1.png';
            app.Button_new.Enable = 'off';
            app.Button_new.Text = '';
            app.Button_open = uibutton(app.Grid1B, 'push');
            app.Button_open.Icon = 'mainMenu_File.png';
            app.Button_open.Enable = 'off';
            app.Button_open.Text = '';
            app.Button_save = uibutton(app.Grid1B, 'push');
            app.Button_save.Icon = 'mainMenu_Save.png';
            app.Button_save.Enable = 'off';
            app.Button_save.Text = '';
            app.Image = uiimage(app.Grid1B);
            app.Image.ImageSource = 'Line.png';
            app.Image_2 = uiimage(app.Grid1B);
            app.Image_2.ImageSource = 'Line.png';
            app.Button_open_2 = uibutton(app.Grid1B, 'push');
            app.Button_open_2.ButtonPushedFcn = createCallbackFcn(app, @Button_open_2Pushed, true);
            app.Button_open_2.Icon = 'Toolbar_Icon3.png';
            app.Button_open_2.Enable = 'off';
            app.Button_open_2.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.ContentLabel = uilabel(app.GridLayout4);
            app.ContentLabel.Text = 'Conteúdo:';
            app.FreeText = uitextarea(app.GridLayout4);
            app.FreeText.Editable = 'off';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.GridLayout2 = uigridlayout(app.GridLayout5);
            app.HTML = uihtml(app.GridLayout2);
            app.Panel = uipanel(app.Grid1);
            app.GridLayout2_2 = uigridlayout(app.Panel);
            app.Sublabel1 = uilabel(app.GridLayout2_2);
            app.Sublabel1.Interpreter = 'html';
            app.Sublabel1.Text = 'PLANO DE MONITORAÇAO DO ESPECTRO DE RADIOFREQUÊNCIAS';
            app.Sublabel2 = uilabel(app.Grid1);
            app.Sublabel2.Text = {'v. 1.00 (12/03/2022)'; 'Centralizador PMEC 2022'};
            app.ModelLabel = uilabel(app.Grid1);
            app.ModelLabel.Text = 'MODELO:';
            app.GridLayout6 = uigridlayout(app.Grid1);
            app.Warn = uiimage(app.GridLayout6);
            app.Warn.Visible = 'off';
            app.Warn.ImageSource = 'Warn_Red.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTemplate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end